classdef M2_Mockup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        P_Botones_Instalacion_Hibrido   matlab.ui.container.Panel
        B_Mostrar_2                     matlab.ui.control.Button
        B_Solucion_2                    matlab.ui.control.Button
        P_Solucion_Hibrido              matlab.ui.container.Panel
        SistemaHbridoLabel_2            matlab.ui.control.Label
        Fi_Costo_Total_Solucion_2       matlab.ui.control.NumericEditField
        COSTOTOTALLabel_2               matlab.ui.control.Label
        Fi_Costo_Mantenimiento_Solucion_2  matlab.ui.control.NumericEditField
        COSTOMANTENIMIENTOLabel_2       matlab.ui.control.Label
        Fi_Costo_Compra_Solucion_2      matlab.ui.control.NumericEditField
        COSTOCOMPRAEditFieldLabel_2     matlab.ui.control.Label
        Fi_Costo_Instalacion_Solucion_2  matlab.ui.control.NumericEditField
        COSTOINSTALACIONEditFieldLabel_2  matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        EditField_6                     matlab.ui.control.NumericEditField
        CheckBox_5                      matlab.ui.control.CheckBox
        MODELOEditField_6               matlab.ui.control.EditField
        MODELOEditField_6Label          matlab.ui.control.Label
        INVERSORLabel_5                 matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        EditField_4                     matlab.ui.control.NumericEditField
        MODELOEditField_4               matlab.ui.control.EditField
        MODELOEditField_4Label          matlab.ui.control.Label
        PANELLabel_4                    matlab.ui.control.Label
        I_Imagen_Solucion_2             matlab.ui.control.Image
        P_Mostrar_Hibrido               matlab.ui.container.Panel
        SistemaHbridoLabel              matlab.ui.control.Label
        P_Panel_5                       matlab.ui.container.Panel
        Fi_Tiempo_Vida_Inversor_2       matlab.ui.control.NumericEditField
        TIEMPODELabel_6                 matlab.ui.control.Label
        Fi_Costo_Inversor_2             matlab.ui.control.NumericEditField
        VOLTAJELabel_6                  matlab.ui.control.Label
        Fi_VAdmisible_Inversor_2        matlab.ui.control.NumericEditField
        ANCHOLabel_6                    matlab.ui.control.Label
        Fi_Potencia_Inversor_2          matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel_5        matlab.ui.control.Label
        Fi_Modelo_Inversor_2            matlab.ui.control.EditField
        MODELOEditFieldLabel_7          matlab.ui.control.Label
        INVERSORLabel_4                 matlab.ui.control.Label
        I_Imagen_Inversor_2             matlab.ui.control.Image
        P_Panel_4                       matlab.ui.container.Panel
        Fi_Tiempo_Vida_Panel_2          matlab.ui.control.NumericEditField
        TIEMPODELabel_4                 matlab.ui.control.Label
        Fi_Alto_Panel_2                 matlab.ui.control.NumericEditField
        ALTOLabel_3                     matlab.ui.control.Label
        Fi_Voltaje_Panel_2              matlab.ui.control.NumericEditField
        VOLTAJELabel_4                  matlab.ui.control.Label
        Fi_Costo_Panel_2                matlab.ui.control.NumericEditField
        COSTOLabel_3                    matlab.ui.control.Label
        Fi_Ancho_Panel_2                matlab.ui.control.NumericEditField
        ANCHOLabel_4                    matlab.ui.control.Label
        Fi_Potencia_Panel_2             matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel_4        matlab.ui.control.Label
        Fi_Modelo_Panel_2               matlab.ui.control.EditField
        MODELOEditFieldLabel_5          matlab.ui.control.Label
        PANELLabel_3                    matlab.ui.control.Label
        I_Imagen_Panel_2                matlab.ui.control.Image
        P_inicio_Instalacion            matlab.ui.container.Panel
        B_System_YY                     matlab.ui.control.Button
        B_System_Hibrido                matlab.ui.control.Button
        CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel  matlab.ui.control.Label
        P_Botones_Inicio                matlab.ui.container.Panel
        Container_Botones               matlab.ui.container.GridLayout
        B_EnergiaProducida              matlab.ui.control.Button
        B_Instalacion                   matlab.ui.control.Button
        B_CalculadoraConsumo            matlab.ui.control.Button
        P_Solucion                      matlab.ui.container.Panel
        Fi_Costo_Total_Solucion         matlab.ui.control.NumericEditField
        COSTOTOTALLabel                 matlab.ui.control.Label
        Fi_Costo_Mantenimiento_Solucion  matlab.ui.control.NumericEditField
        COSTOMANTENIMIENTOLabel         matlab.ui.control.Label
        Fi_Costo_Compra_Solucion        matlab.ui.control.NumericEditField
        COSTOCOMPRAEditFieldLabel       matlab.ui.control.Label
        Fi_Costo_Instalacion_Solucion   matlab.ui.control.NumericEditField
        COSTOINSTALACIONEditFieldLabel  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        EditField_3                     matlab.ui.control.NumericEditField
        CheckBox_3                      matlab.ui.control.CheckBox
        MODELOEditField_3               matlab.ui.control.EditField
        MODELOEditField_3Label_2        matlab.ui.control.Label
        INVERSORLabel_2                 matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        EditField_2                     matlab.ui.control.NumericEditField
        CheckBox_2                      matlab.ui.control.CheckBox
        MODELOEditField_2               matlab.ui.control.EditField
        MODELOEditField_2Label_2        matlab.ui.control.Label
        BATERIALabel_2                  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        EditField                       matlab.ui.control.NumericEditField
        MODELOEditField                 matlab.ui.control.EditField
        MODELOEditFieldLabel_2          matlab.ui.control.Label
        PANELLabel_2                    matlab.ui.control.Label
        I_Imagen_Solucion               matlab.ui.control.Image
        P_Mostrar                       matlab.ui.container.Panel
        P_Panel_3                       matlab.ui.container.Panel
        Fi_Tiempo_Vida_Inversor         matlab.ui.control.NumericEditField
        TIEMPODELabel_3                 matlab.ui.control.Label
        Fi_Costo_Inversor               matlab.ui.control.NumericEditField
        VOLTAJELabel_3                  matlab.ui.control.Label
        Fi_VAdmisible_Inversor          matlab.ui.control.NumericEditField
        ANCHOLabel_3                    matlab.ui.control.Label
        Fi_Potencia_Inversor            matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel_3        matlab.ui.control.Label
        Fi_Modelo_Inversor              matlab.ui.control.EditField
        MODELOEditFieldLabel_4          matlab.ui.control.Label
        INVERSORLabel_3                 matlab.ui.control.Label
        I_Imagen_Inversor               matlab.ui.control.Image
        P_Bateria_2                     matlab.ui.container.Panel
        Fi_Tiempo_Vida_Bateria          matlab.ui.control.NumericEditField
        TIEMPODELabel_2                 matlab.ui.control.Label
        Fi_Alto_Bateria                 matlab.ui.control.NumericEditField
        ALTOLabel_2                     matlab.ui.control.Label
        Fi_Largo_Bateria                matlab.ui.control.NumericEditField
        VOLTAJELabel_2                  matlab.ui.control.Label
        Fi_Costo_Bateria                matlab.ui.control.NumericEditField
        COSTOLabel_2                    matlab.ui.control.Label
        Fi_Ancho_Bateria                matlab.ui.control.NumericEditField
        ANCHOLabel_2                    matlab.ui.control.Label
        Fi_Capacidad_Bateria            matlab.ui.control.NumericEditField
        CAPACIDADLabel                  matlab.ui.control.Label
        Fi_Modelo_Bateria               matlab.ui.control.EditField
        MODELOEditFieldLabel_3          matlab.ui.control.Label
        BATERIALabel_3                  matlab.ui.control.Label
        I_Imagen_Bateria                matlab.ui.control.Image
        P_Panel_1                       matlab.ui.container.Panel
        Fi_Tiempo_Vida_Panel            matlab.ui.control.NumericEditField
        TIEMPODELabel                   matlab.ui.control.Label
        Fi_Alto_Panel                   matlab.ui.control.NumericEditField
        ALTOLabel                       matlab.ui.control.Label
        Fi_Voltaje_Panel                matlab.ui.control.NumericEditField
        VOLTAJELabel                    matlab.ui.control.Label
        Fi_Costo_Panel                  matlab.ui.control.NumericEditField
        COSTOLabel                      matlab.ui.control.Label
        Fi_Ancho_Panel                  matlab.ui.control.NumericEditField
        ANCHOLabel                      matlab.ui.control.Label
        Fi_Potencia_Panel               matlab.ui.control.NumericEditField
        POTENCIAEditFieldLabel          matlab.ui.control.Label
        Fi_Modelo_Panel                 matlab.ui.control.EditField
        MODELOEditFieldLabel            matlab.ui.control.Label
        PANELLabel                      matlab.ui.control.Label
        I_Imagen_Panel                  matlab.ui.control.Image
        P_Botones_Instalacion           matlab.ui.container.Panel
        B_Mostrar                       matlab.ui.control.Button
        B_Solucion                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: B_Mostrar
        function B_MostrarButtonPushed(app, event)
            app.P_Solucion.Visible ="off";
            app.P_Mostrar.Visible = "on";
        end

        % Button pushed function: B_Solucion
        function B_SolucionButtonPushed(app, event)
            app.P_Solucion.Visible ="on";
            app.P_Mostrar.Visible = "off";
        end

        % Button pushed function: B_Instalacion
        function B_InstalacionButtonPushed(app, event)
            app.P_inicio_Instalacion.Visible = "on";
            app.P_Botones_Instalacion.Visible = "off";
            app.P_Botones_Instalacion_Hibrido.Visible = "off";
            app.P_Solucion_Hibrido.Visible = "off";
            app.P_Mostrar_Hibrido.Visible = "off";
        
        end

        % Button pushed function: B_System_YY
        function B_System_YYButtonPushed(app, event)
            app.P_inicio_Instalacion.Visible = "off";
            app.P_Botones_Instalacion.Visible = "on";
            B_SolucionButtonPushed(app, event);
         
            M2_MostrarCostos(app);

            %Para boton Mostrar
            %PANEL
            M2_MostrarModeloPanel(app);
            
            %BATERIA
            M2_MostrarModeloBateria(app);

            %INVERSOR
            M2_MostrarModeloInversor(app);
            
        end

        % Button pushed function: B_System_Hibrido
        function B_System_HibridoButtonPushed(app, event)
            app.P_inicio_Instalacion.Visible = "off";
            app.P_Botones_Instalacion_Hibrido.Visible = "on";

            B_Solucion_2ButtonPushed(app, event);
            
        end

        % Callback function: not associated with a component
        function P_Botones_Instalacion_HibridoButtonDown(app, event)

        end

        % Button pushed function: B_Solucion_2
        function B_Solucion_2ButtonPushed(app, event)
            app.P_Solucion_Hibrido.Visible = "on";
            app.P_Mostrar_Hibrido.Visible = "off";
        end

        % Button pushed function: B_Mostrar_2
        function B_Mostrar_2ButtonPushed(app, event)
            app.P_Solucion_Hibrido.Visible = "off";
            app.P_Mostrar_Hibrido.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.P_Botones_Instalacion = uipanel(app.UIFigure);
            app.P_Botones_Instalacion.Visible = 'off';
            app.B_Solucion = uibutton(app.P_Botones_Instalacion, 'push');
            app.B_Solucion.ButtonPushedFcn = createCallbackFcn(app, @B_SolucionButtonPushed, true);
            app.B_Solucion.Text = 'SOLUCION';
            app.B_Mostrar = uibutton(app.P_Botones_Instalacion, 'push');
            app.B_Mostrar.ButtonPushedFcn = createCallbackFcn(app, @B_MostrarButtonPushed, true);
            app.B_Mostrar.Text = 'MOSTRAR';
            app.P_Mostrar = uipanel(app.UIFigure);
            app.P_Mostrar.Visible = 'off';
            app.P_Panel_1 = uipanel(app.P_Mostrar);
            app.I_Imagen_Panel = uiimage(app.P_Panel_1);
            app.PANELLabel = uilabel(app.P_Panel_1);
            app.PANELLabel.Text = 'PANEL';
            app.MODELOEditFieldLabel = uilabel(app.P_Panel_1);
            app.MODELOEditFieldLabel.Text = 'MODELO';
            app.Fi_Modelo_Panel = uieditfield(app.P_Panel_1, 'text');
            app.Fi_Modelo_Panel.Editable = 'off';
            app.POTENCIAEditFieldLabel = uilabel(app.P_Panel_1);
            app.POTENCIAEditFieldLabel.Text = 'POTENCIA';
            app.Fi_Potencia_Panel = uieditfield(app.P_Panel_1, 'numeric');
            app.Fi_Potencia_Panel.Editable = 'off';
            app.ANCHOLabel = uilabel(app.P_Panel_1);
            app.ANCHOLabel.Text = 'ANCHO';
            app.Fi_Ancho_Panel = uieditfield(app.P_Panel_1, 'numeric');
            app.Fi_Ancho_Panel.Editable = 'off';
            app.COSTOLabel = uilabel(app.P_Panel_1);
            app.COSTOLabel.Text = 'COSTO';
            app.Fi_Costo_Panel = uieditfield(app.P_Panel_1, 'numeric');
            app.Fi_Costo_Panel.Editable = 'off';
            app.VOLTAJELabel = uilabel(app.P_Panel_1);
            app.VOLTAJELabel.Text = 'VOLTAJE';
            app.Fi_Voltaje_Panel = uieditfield(app.P_Panel_1, 'numeric');
            app.Fi_Voltaje_Panel.Editable = 'off';
            app.ALTOLabel = uilabel(app.P_Panel_1);
            app.ALTOLabel.Text = 'ALTO';
            app.Fi_Alto_Panel = uieditfield(app.P_Panel_1, 'numeric');
            app.Fi_Alto_Panel.Editable = 'off';
            app.TIEMPODELabel = uilabel(app.P_Panel_1);
            app.TIEMPODELabel.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Panel = uieditfield(app.P_Panel_1, 'numeric');
            app.Fi_Tiempo_Vida_Panel.Editable = 'off';
            app.P_Bateria_2 = uipanel(app.P_Mostrar);
            app.I_Imagen_Bateria = uiimage(app.P_Bateria_2);
            app.BATERIALabel_3 = uilabel(app.P_Bateria_2);
            app.BATERIALabel_3.Text = 'BATERIA';
            app.MODELOEditFieldLabel_3 = uilabel(app.P_Bateria_2);
            app.MODELOEditFieldLabel_3.Text = 'MODELO';
            app.Fi_Modelo_Bateria = uieditfield(app.P_Bateria_2, 'text');
            app.Fi_Modelo_Bateria.Editable = 'off';
            app.CAPACIDADLabel = uilabel(app.P_Bateria_2);
            app.CAPACIDADLabel.Text = 'CAPACIDAD';
            app.Fi_Capacidad_Bateria = uieditfield(app.P_Bateria_2, 'numeric');
            app.Fi_Capacidad_Bateria.Editable = 'off';
            app.ANCHOLabel_2 = uilabel(app.P_Bateria_2);
            app.ANCHOLabel_2.Text = 'ANCHO';
            app.Fi_Ancho_Bateria = uieditfield(app.P_Bateria_2, 'numeric');
            app.Fi_Ancho_Bateria.Editable = 'off';
            app.COSTOLabel_2 = uilabel(app.P_Bateria_2);
            app.COSTOLabel_2.Text = 'COSTO';
            app.Fi_Costo_Bateria = uieditfield(app.P_Bateria_2, 'numeric');
            app.Fi_Costo_Bateria.Editable = 'off';
            app.VOLTAJELabel_2 = uilabel(app.P_Bateria_2);
            app.VOLTAJELabel_2.Text = 'LARGO';
            app.Fi_Largo_Bateria = uieditfield(app.P_Bateria_2, 'numeric');
            app.Fi_Largo_Bateria.Editable = 'off';
            app.ALTOLabel_2 = uilabel(app.P_Bateria_2);
            app.ALTOLabel_2.Text = 'ALTO';
            app.Fi_Alto_Bateria = uieditfield(app.P_Bateria_2, 'numeric');
            app.Fi_Alto_Bateria.Editable = 'off';
            app.TIEMPODELabel_2 = uilabel(app.P_Bateria_2);
            app.TIEMPODELabel_2.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Bateria = uieditfield(app.P_Bateria_2, 'numeric');
            app.Fi_Tiempo_Vida_Bateria.Editable = 'off';
            app.P_Panel_3 = uipanel(app.P_Mostrar);
            app.I_Imagen_Inversor = uiimage(app.P_Panel_3);
            app.INVERSORLabel_3 = uilabel(app.P_Panel_3);
            app.INVERSORLabel_3.Text = 'INVERSOR';
            app.MODELOEditFieldLabel_4 = uilabel(app.P_Panel_3);
            app.MODELOEditFieldLabel_4.Text = 'MODELO';
            app.Fi_Modelo_Inversor = uieditfield(app.P_Panel_3, 'text');
            app.POTENCIAEditFieldLabel_3 = uilabel(app.P_Panel_3);
            app.POTENCIAEditFieldLabel_3.Text = 'POTENCIA';
            app.Fi_Potencia_Inversor = uieditfield(app.P_Panel_3, 'numeric');
            app.Fi_Potencia_Inversor.Editable = 'off';
            app.ANCHOLabel_3 = uilabel(app.P_Panel_3);
            app.ANCHOLabel_3.Text = 'V. ADMISIBLE';
            app.Fi_VAdmisible_Inversor = uieditfield(app.P_Panel_3, 'numeric');
            app.Fi_VAdmisible_Inversor.Editable = 'off';
            app.VOLTAJELabel_3 = uilabel(app.P_Panel_3);
            app.VOLTAJELabel_3.Text = 'COSTO';
            app.Fi_Costo_Inversor = uieditfield(app.P_Panel_3, 'numeric');
            app.Fi_Costo_Inversor.Editable = 'off';
            app.TIEMPODELabel_3 = uilabel(app.P_Panel_3);
            app.TIEMPODELabel_3.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Inversor = uieditfield(app.P_Panel_3, 'numeric');
            app.Fi_Tiempo_Vida_Inversor.Editable = 'off';
            app.P_Solucion = uipanel(app.UIFigure);
            app.P_Solucion.Visible = 'off';
            app.I_Imagen_Solucion = uiimage(app.P_Solucion);
            app.Panel = uipanel(app.P_Solucion);
            app.PANELLabel_2 = uilabel(app.Panel);
            app.PANELLabel_2.Text = 'PANEL';
            app.MODELOEditFieldLabel_2 = uilabel(app.Panel);
            app.MODELOEditFieldLabel_2.Text = 'MODELO';
            app.MODELOEditField = uieditfield(app.Panel, 'text');
            app.MODELOEditField.Editable = 'off';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.ValueDisplayFormat = '%.0f';
            app.Panel_2 = uipanel(app.P_Solucion);
            app.BATERIALabel_2 = uilabel(app.Panel_2);
            app.BATERIALabel_2.Text = 'BATERIA';
            app.MODELOEditField_2Label_2 = uilabel(app.Panel_2);
            app.MODELOEditField_2Label_2.Text = 'MODELO';
            app.MODELOEditField_2 = uieditfield(app.Panel_2, 'text');
            app.MODELOEditField_2.Editable = 'off';
            app.CheckBox_2 = uicheckbox(app.Panel_2);
            app.CheckBox_2.Text = '';
            app.EditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.Panel_3 = uipanel(app.P_Solucion);
            app.INVERSORLabel_2 = uilabel(app.Panel_3);
            app.INVERSORLabel_2.Text = 'INVERSOR';
            app.MODELOEditField_3Label_2 = uilabel(app.Panel_3);
            app.MODELOEditField_3Label_2.Text = 'MODELO';
            app.MODELOEditField_3 = uieditfield(app.Panel_3, 'text');
            app.MODELOEditField_3.Editable = 'off';
            app.CheckBox_3 = uicheckbox(app.Panel_3);
            app.CheckBox_3.Text = '';
            app.EditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.EditField_3.ValueDisplayFormat = '%.0f';
            app.COSTOINSTALACIONEditFieldLabel = uilabel(app.P_Solucion);
            app.COSTOINSTALACIONEditFieldLabel.Text = 'COSTO INSTALACION';
            app.Fi_Costo_Instalacion_Solucion = uieditfield(app.P_Solucion, 'numeric');
            app.Fi_Costo_Instalacion_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Instalacion_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Instalacion_Solucion.Editable = 'off';
            app.COSTOCOMPRAEditFieldLabel = uilabel(app.P_Solucion);
            app.COSTOCOMPRAEditFieldLabel.Text = 'COSTO COMPRA';
            app.Fi_Costo_Compra_Solucion = uieditfield(app.P_Solucion, 'numeric');
            app.Fi_Costo_Compra_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Compra_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Compra_Solucion.Editable = 'off';
            app.COSTOMANTENIMIENTOLabel = uilabel(app.P_Solucion);
            app.COSTOMANTENIMIENTOLabel.Text = {'COSTO'; 'MANTENIMIENTO'};
            app.Fi_Costo_Mantenimiento_Solucion = uieditfield(app.P_Solucion, 'numeric');
            app.Fi_Costo_Mantenimiento_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Mantenimiento_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Mantenimiento_Solucion.Editable = 'off';
            app.COSTOTOTALLabel = uilabel(app.P_Solucion);
            app.COSTOTOTALLabel.Text = 'COSTO TOTAL';
            app.Fi_Costo_Total_Solucion = uieditfield(app.P_Solucion, 'numeric');
            app.Fi_Costo_Total_Solucion.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Total_Solucion.AllowEmpty = 'on';
            app.Fi_Costo_Total_Solucion.Editable = 'off';
            app.P_Botones_Inicio = uipanel(app.UIFigure);
            app.Container_Botones = uigridlayout(app.P_Botones_Inicio);
            app.B_CalculadoraConsumo = uibutton(app.Container_Botones, 'push');
            app.B_CalculadoraConsumo.Text = 'Calculadora de Consumo';
            app.B_Instalacion = uibutton(app.Container_Botones, 'push');
            app.B_Instalacion.ButtonPushedFcn = createCallbackFcn(app, @B_InstalacionButtonPushed, true);
            app.B_Instalacion.Text = 'Instalación';
            app.B_EnergiaProducida = uibutton(app.Container_Botones, 'push');
            app.B_EnergiaProducida.Text = 'Energía Producida';
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel = uilabel(app.UIFigure);
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel.Text = 'Calculadora de ROI en la implementación de energía solar vs eléctrica';
            app.P_inicio_Instalacion = uipanel(app.UIFigure);
            app.P_inicio_Instalacion.Visible = 'off';
            app.B_System_Hibrido = uibutton(app.P_inicio_Instalacion, 'push');
            app.B_System_Hibrido.ButtonPushedFcn = createCallbackFcn(app, @B_System_HibridoButtonPushed, true);
            app.B_System_Hibrido.Text = 'Sistema Hibrido';
            app.B_System_YY = uibutton(app.P_inicio_Instalacion, 'push');
            app.B_System_YY.ButtonPushedFcn = createCallbackFcn(app, @B_System_YYButtonPushed, true);
            app.B_System_YY.Text = 'Sistema YYY';
            app.P_Mostrar_Hibrido = uipanel(app.UIFigure);
            app.P_Mostrar_Hibrido.Visible = 'off';
            app.P_Panel_4 = uipanel(app.P_Mostrar_Hibrido);
            app.I_Imagen_Panel_2 = uiimage(app.P_Panel_4);
            app.PANELLabel_3 = uilabel(app.P_Panel_4);
            app.PANELLabel_3.Text = 'PANEL';
            app.MODELOEditFieldLabel_5 = uilabel(app.P_Panel_4);
            app.MODELOEditFieldLabel_5.Text = 'MODELO';
            app.Fi_Modelo_Panel_2 = uieditfield(app.P_Panel_4, 'text');
            app.Fi_Modelo_Panel_2.Editable = 'off';
            app.POTENCIAEditFieldLabel_4 = uilabel(app.P_Panel_4);
            app.POTENCIAEditFieldLabel_4.Text = 'POTENCIA';
            app.Fi_Potencia_Panel_2 = uieditfield(app.P_Panel_4, 'numeric');
            app.Fi_Potencia_Panel_2.Editable = 'off';
            app.ANCHOLabel_4 = uilabel(app.P_Panel_4);
            app.ANCHOLabel_4.Text = 'ANCHO';
            app.Fi_Ancho_Panel_2 = uieditfield(app.P_Panel_4, 'numeric');
            app.Fi_Ancho_Panel_2.Editable = 'off';
            app.COSTOLabel_3 = uilabel(app.P_Panel_4);
            app.COSTOLabel_3.Text = 'COSTO';
            app.Fi_Costo_Panel_2 = uieditfield(app.P_Panel_4, 'numeric');
            app.Fi_Costo_Panel_2.Editable = 'off';
            app.VOLTAJELabel_4 = uilabel(app.P_Panel_4);
            app.VOLTAJELabel_4.Text = 'VOLTAJE';
            app.Fi_Voltaje_Panel_2 = uieditfield(app.P_Panel_4, 'numeric');
            app.Fi_Voltaje_Panel_2.Editable = 'off';
            app.ALTOLabel_3 = uilabel(app.P_Panel_4);
            app.ALTOLabel_3.Text = 'ALTO';
            app.Fi_Alto_Panel_2 = uieditfield(app.P_Panel_4, 'numeric');
            app.Fi_Alto_Panel_2.Editable = 'off';
            app.TIEMPODELabel_4 = uilabel(app.P_Panel_4);
            app.TIEMPODELabel_4.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Panel_2 = uieditfield(app.P_Panel_4, 'numeric');
            app.Fi_Tiempo_Vida_Panel_2.Editable = 'off';
            app.P_Panel_5 = uipanel(app.P_Mostrar_Hibrido);
            app.I_Imagen_Inversor_2 = uiimage(app.P_Panel_5);
            app.INVERSORLabel_4 = uilabel(app.P_Panel_5);
            app.INVERSORLabel_4.Text = 'INVERSOR';
            app.MODELOEditFieldLabel_7 = uilabel(app.P_Panel_5);
            app.MODELOEditFieldLabel_7.Text = 'MODELO';
            app.Fi_Modelo_Inversor_2 = uieditfield(app.P_Panel_5, 'text');
            app.POTENCIAEditFieldLabel_5 = uilabel(app.P_Panel_5);
            app.POTENCIAEditFieldLabel_5.Text = 'POTENCIA';
            app.Fi_Potencia_Inversor_2 = uieditfield(app.P_Panel_5, 'numeric');
            app.Fi_Potencia_Inversor_2.Editable = 'off';
            app.ANCHOLabel_6 = uilabel(app.P_Panel_5);
            app.ANCHOLabel_6.Text = 'V. ADMISIBLE';
            app.Fi_VAdmisible_Inversor_2 = uieditfield(app.P_Panel_5, 'numeric');
            app.Fi_VAdmisible_Inversor_2.Editable = 'off';
            app.VOLTAJELabel_6 = uilabel(app.P_Panel_5);
            app.VOLTAJELabel_6.Text = 'COSTO';
            app.Fi_Costo_Inversor_2 = uieditfield(app.P_Panel_5, 'numeric');
            app.Fi_Costo_Inversor_2.Editable = 'off';
            app.TIEMPODELabel_6 = uilabel(app.P_Panel_5);
            app.TIEMPODELabel_6.Text = {'TIEMPO DE '; 'VIDA'};
            app.Fi_Tiempo_Vida_Inversor_2 = uieditfield(app.P_Panel_5, 'numeric');
            app.Fi_Tiempo_Vida_Inversor_2.Editable = 'off';
            app.SistemaHbridoLabel = uilabel(app.P_Mostrar_Hibrido);
            app.SistemaHbridoLabel.Text = 'Sistema Híbrido';
            app.P_Solucion_Hibrido = uipanel(app.UIFigure);
            app.P_Solucion_Hibrido.Visible = 'off';
            app.I_Imagen_Solucion_2 = uiimage(app.P_Solucion_Hibrido);
            app.Panel_4 = uipanel(app.P_Solucion_Hibrido);
            app.PANELLabel_4 = uilabel(app.Panel_4);
            app.PANELLabel_4.Text = 'PANEL';
            app.MODELOEditField_4Label = uilabel(app.Panel_4);
            app.MODELOEditField_4Label.Text = 'MODELO';
            app.MODELOEditField_4 = uieditfield(app.Panel_4, 'text');
            app.MODELOEditField_4.Editable = 'off';
            app.EditField_4 = uieditfield(app.Panel_4, 'numeric');
            app.EditField_4.ValueDisplayFormat = '%.0f';
            app.Panel_6 = uipanel(app.P_Solucion_Hibrido);
            app.INVERSORLabel_5 = uilabel(app.Panel_6);
            app.INVERSORLabel_5.Text = 'INVERSOR';
            app.MODELOEditField_6Label = uilabel(app.Panel_6);
            app.MODELOEditField_6Label.Text = 'MODELO';
            app.MODELOEditField_6 = uieditfield(app.Panel_6, 'text');
            app.MODELOEditField_6.Editable = 'off';
            app.CheckBox_5 = uicheckbox(app.Panel_6);
            app.CheckBox_5.Text = '';
            app.EditField_6 = uieditfield(app.Panel_6, 'numeric');
            app.EditField_6.ValueDisplayFormat = '%.0f';
            app.COSTOINSTALACIONEditFieldLabel_2 = uilabel(app.P_Solucion_Hibrido);
            app.COSTOINSTALACIONEditFieldLabel_2.Text = 'COSTO INSTALACION';
            app.Fi_Costo_Instalacion_Solucion_2 = uieditfield(app.P_Solucion_Hibrido, 'numeric');
            app.Fi_Costo_Instalacion_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Instalacion_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Instalacion_Solucion_2.Editable = 'off';
            app.COSTOCOMPRAEditFieldLabel_2 = uilabel(app.P_Solucion_Hibrido);
            app.COSTOCOMPRAEditFieldLabel_2.Text = 'COSTO COMPRA';
            app.Fi_Costo_Compra_Solucion_2 = uieditfield(app.P_Solucion_Hibrido, 'numeric');
            app.Fi_Costo_Compra_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Compra_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Compra_Solucion_2.Editable = 'off';
            app.COSTOMANTENIMIENTOLabel_2 = uilabel(app.P_Solucion_Hibrido);
            app.COSTOMANTENIMIENTOLabel_2.Text = {'COSTO'; 'MANTENIMIENTO'};
            app.Fi_Costo_Mantenimiento_Solucion_2 = uieditfield(app.P_Solucion_Hibrido, 'numeric');
            app.Fi_Costo_Mantenimiento_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Mantenimiento_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Mantenimiento_Solucion_2.Editable = 'off';
            app.COSTOTOTALLabel_2 = uilabel(app.P_Solucion_Hibrido);
            app.COSTOTOTALLabel_2.Text = 'COSTO TOTAL';
            app.Fi_Costo_Total_Solucion_2 = uieditfield(app.P_Solucion_Hibrido, 'numeric');
            app.Fi_Costo_Total_Solucion_2.ValueDisplayFormat = '%.2f';
            app.Fi_Costo_Total_Solucion_2.AllowEmpty = 'on';
            app.Fi_Costo_Total_Solucion_2.Editable = 'off';
            app.SistemaHbridoLabel_2 = uilabel(app.P_Solucion_Hibrido);
            app.SistemaHbridoLabel_2.Text = 'Sistema Híbrido';
            app.P_Botones_Instalacion_Hibrido = uipanel(app.UIFigure);
            app.P_Botones_Instalacion_Hibrido.Visible = 'off';
            app.B_Solucion_2 = uibutton(app.P_Botones_Instalacion_Hibrido, 'push');
            app.B_Solucion_2.ButtonPushedFcn = createCallbackFcn(app, @B_Solucion_2ButtonPushed, true);
            app.B_Solucion_2.Text = 'SOLUCION';
            app.B_Mostrar_2 = uibutton(app.P_Botones_Instalacion_Hibrido, 'push');
            app.B_Mostrar_2.ButtonPushedFcn = createCallbackFcn(app, @B_Mostrar_2ButtonPushed, true);
            app.B_Mostrar_2.Text = 'MOSTRAR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = M2_Mockup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end