classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel2                          matlab.ui.container.Panel
        Image2_4                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        CostototalEditField             matlab.ui.control.EditField
        CostototalEditFieldLabel        matlab.ui.control.Label
        TerrenonecesarioEditField       matlab.ui.control.EditField
        PanelesnecesariosEditField      matlab.ui.control.EditField
        BateriasnecesariasEditField     matlab.ui.control.EditField
        TerrenonecesarioEditFieldLabel  matlab.ui.control.Label
        PanelesnecesariosEditFieldLabel  matlab.ui.control.Label
        BateriasnecesariasEditFieldLabel  matlab.ui.control.Label
        CostoDeInstalacion              matlab.ui.control.Label
        IngreseConsumo                  matlab.ui.control.NumericEditField
        IngresesuconsumomensualLabel    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Panel_2                         matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        EnergaProducidaButton           matlab.ui.control.Button
        InstalacionButton               matlab.ui.control.Button
        CalculadoradeConsumoButton      matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        AgregarButton                   matlab.ui.control.Button
        CalcularPotenciaButton          matlab.ui.control.Button
        ModificarButton                 matlab.ui.control.Button
        EliminarButton                  matlab.ui.control.Button
        LimpiarButton                   matlab.ui.control.Button
        SeleccioneDropDown              matlab.ui.control.DropDown
        ResultadoLabel                  matlab.ui.control.Label
        CantidadDiasEditField           matlab.ui.control.NumericEditField
        DiaspromediodeusoalasemanaEditFieldLabel  matlab.ui.control.Label
        UsoEnHorasEditField             matlab.ui.control.NumericEditField
        UsopromediodiarioenhorasEditFieldLabel  matlab.ui.control.Label
        PotenciaEnWattsEditField        matlab.ui.control.NumericEditField
        PotenciaqueconsumeenWattsEditFieldLabel  matlab.ui.control.Label
        NombreEditField                 matlab.ui.control.EditField
        NombreEditFieldLabel            matlab.ui.control.Label
        TipoDropDown                    matlab.ui.control.DropDown
        TipoDropDownLabel               matlab.ui.control.Label
        CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        %crea un usuario con sus parametros (id, nombre, direccion)
        usuario = C_Usuario(1, "Paulo", "Arequipa");
    end
    
    methods (Access = public)
        function limpiarValores(app)
            %hace que el valor de la potencia, uso en horas, cantidad de dias de uso,
            %el tipo y el nombre vuelvan a sus valores iniciales
            app.PotenciaEnWattsEditField.Value = 0;
            app.UsoEnHorasEditField.Value = 0;
            app.CantidadDiasEditField.Value = 0;
            app.TipoDropDown.Value = "Esencial";
            app.NombreEditField.Value = "";
        end
        function ActualizarCallback(app)
          try
            consumo = app.PotenciaEnWattsEditField.Value;%captura el consumo
            horas = app.UsoEnHorasEditField.Value;%captura el uso en horas
            dias = app.CantidadDiasEditField.Value;%captura el tiempo en dias
            tipo = app.TipoDropDown.Value;%captura el tipo de electrodomestico
            nombre = app.NombreEditField.Value;%captura el nombre}
            %crea un electrodomestico con su constructor, se pasan como
            %argumento los valores obtenidos previamente
            equipo = C_Electrodomestico(nombre,consumo, horas, dias, tipo);
            %modifica un equipo(electrodomestico) del usuario a traves del metodo
            %Mod_Electrodomestico() se le pasa por argumente el equipo
            app.usuario = app.usuario.Mod_Electrodomestico(equipo);
            %muestra mensaje en caso no halla error
            message = "El consumo actualizado a: "+ equipo.ConsumoTotal(1)+" Watts al mes";
            %cambia el valor del boton de Actualizar a Agregar
            app.AgregarButton.Text = "Agregar";
            %cambia el callback a AgregarCallback
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarCallback, true);
            %habilita los demas botones y hace no visisble a SeleccioneDropDown
            %ya que ya se modifico el valor
            app.NombreEditField.Editable = "on";
            app.SeleccioneDropDown.Visible = "off";
            app.ModificarButton.Enable = "on";
            app.LimpiarButton.Enable = "on";
            app.CalcularPotenciaButton.Enable = "on";
            app.EliminarButton.Enable = "on";
            limpiarValores(app);%limpia los campos
          catch exception%captura excepciones
              message = exception.message;
          end
          app.ResultadoLabel.Text = message;%muestra el mensaje
        end
    end
    
    methods (Access = private)
            % Método para mostrar la interfaz de instalación
        function showInstallationView(app)
            % Ocultar los componentes actuales
            app.AgregarButton.Visible = 'off';
            app.CalcularPotenciaButton.Visible = 'off';
            app.ModificarButton.Visible = 'off';
            app.EliminarButton.Visible = 'off';
            app.LimpiarButton.Visible = 'off';
            app.SeleccioneDropDown.Visible = 'off';
            app.ResultadoLabel.Visible = 'off';
            app.CantidadDiasEditField.Visible = 'off';
            app.UsoEnHorasEditField.Visible = 'off';
            app.PotenciaEnWattsEditField.Visible = 'off';
            app.NombreEditField.Visible = 'off';
            app.TipoDropDown.Visible = 'off';
    
            % Mostrar los componentes de instalación
            app.Panel2.Visible = 'on';
            app.Image2_4.Visible = 'on';
            app.Image2_3.Visible = 'on';
            app.Image2_2.Visible = 'on';
            app.Image2.Visible = 'on';
            app.CostototalEditField.Visible = 'on';
            app.TerrenonecesarioEditField.Visible = 'on';
            app.PanelesnecesariosEditField.Visible = 'on';
            app.BateriasnecesariasEditField.Visible = 'on';
            app.CostoDeInstalacion.Visible = 'on';
            app.IngreseConsumo.Visible = 'on';

        end
    
        % Método para manejar el guardado de datos en la instalación
        function SaveButtonPushed(app, event)
            % Código para guardar los datos
            disp('Datos guardados');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AgregarButton
        function AgregarCallback(app, event)
            app.ResultadoLabel.Visible = "on";
            try
                %ver explicacion de ActualizarCallback
                consumo = app.PotenciaEnWattsEditField.Value;
                horas = app.UsoEnHorasEditField.Value;
                dias = app.CantidadDiasEditField.Value;
                tipo = app.TipoDropDown.Value;
                nombre = app.NombreEditField.Value;
                producto = C_Electrodomestico(nombre,consumo, horas, dias, tipo);
                %en vez de modificar, agrega, el metodo ya tiene validacion
                %en base a el nombre
                app.usuario = app.usuario.Add_Electrodomestico(producto);
                %mensaje que muestra el consumo de 1 mes
                message = "El consumo de "+nombre+" es: "+ producto.ConsumoTotal(1) +" Watts al mes";
                %ver explicacion de ActualizarCallback
                app.ModificarButton.Enable = "on";
                app.LimpiarButton.Enable = "on";
                app.CalcularPotenciaButton.Enable = "on";
                app.EliminarButton.Enable = "on";
                app.SeleccioneDropDown.Visible = "off";
                limpiarValores(app);
            catch exception
                message = exception.message;
            end
            app.ResultadoLabel.Text = message;                
        end

        % Button pushed function: CalcularPotenciaButton
        function CalcularTotalCallback(app, event)
            total = app.usuario.ConsumoTotal(1);
            consumoTotalStr = num2str("El total es: "+total+" Watts");
            app.ResultadoLabel.Text = consumoTotalStr;
        end

        % Button pushed function: LimpiarButton
        function LimpiarCallback(app, event)
            %el arreglo de electrodomesticos del usuario se hace vacio se
            %comienza de nuevo
            app.usuario.electrodomesticos = C_Electrodomestico.empty;
            %deja habilitado solo el boton de agregar
            app.ResultadoLabel.Visible = "off";
            app.ModificarButton.Enable = "off";
            app.LimpiarButton.Enable = "off";
            app.CalcularPotenciaButton.Enable = "off";
            app.EliminarButton.Enable = "off";
            limpiarValores(app);%limpia los valores
        end

        % Button pushed function: EliminarButton
        function EliminarCallback(app, event)
            %no funciona aun
           
            app.ResultadoLabel.Visible = 'on';
            try
                app.usuario = app.usuario.pop();
                message = "Eliminado el ultimo elemento";
                
                if isempty(app.usuario.electrodomesticos)
                    app.ModificarButton.Enable = 'off';
                    app.LimpiarButton.Enable = 'off';
                    app.CalcularPotenciaButton.Enable = 'off';
                    app.EliminarButton.Enable = 'off';
                end
            catch exception
                message = exception.message;
            end
            app.ResultadoLabel.Text = message;


        end

        % Button pushed function: ModificarButton
        function ModificarCallback(app, event)
            len = length(app.usuario.electrodomesticos);
            nombresElectrodomesticos = strings(1, (len+1));
            nombresElectrodomesticos(1) = "---";
            for i = 1:len
                nombresElectrodomesticos(i+1) = app.usuario.electrodomesticos(i).nombre;
            end
            app.SeleccioneDropDown.Items = nombresElectrodomesticos;
            app.SeleccioneDropDown.Visible = "on";
        end

        % Clicked callback: SeleccioneDropDown
        function ModificarElectrodomesticoCallback(app, event)
            %deja habilitado solo el selector y el boton agregar
            app.ModificarButton.Enable = "off";
            app.LimpiarButton.Enable = "off";
            app.CalcularPotenciaButton.Enable = "off";
            app.EliminarButton.Enable = "off";
            value = app.SeleccioneDropDown.Value;
            if value == "---"
                %en caso se seleccione la opcion --- vuelve limpia los
                %campos lo cual imposibilita la actualizacion hasta que se
                %seleccione un elemento
                limpiarValores(app);
            else
                %recorre el arreglo de electrodomesticos
                for i = 1:length(app.usuario.electrodomesticos)
                    %obtiene el nombre del i-esimo electrodomestico y lo
                    %guarda en una variable nombre
                    nombre = app.usuario.electrodomesticos(i).nombre;
                    %comprueba si el nombre y el valor(la opcion
                    %seleccionada del dropdown) son iguales
                    if  strcmp(nombre, value)
                        %no se puede cambiar el nombre
                        app.NombreEditField.Editable = "off";
                        %se cambian los valores de los EditFields con
                        %respecto a los valores del equipo
                        app.PotenciaEnWattsEditField.Value = app.usuario.electrodomesticos(i).potencia;
                        app.UsoEnHorasEditField.Value = app.usuario.electrodomesticos(i).horas_dia;
                        app.CantidadDiasEditField.Value = app.usuario.electrodomesticos(i).dia_semana;
                        app.TipoDropDown.Value = app.usuario.electrodomesticos(i).tipo;
                        app.NombreEditField.Value = nombre;
                        %se cambia el nombre del boton de agregar a
                        %actualizar
                        app.AgregarButton.Text = "Actualizar";
                        %cambia el callback a ActualizarCallback
                        app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarCallback);
                        break;
                    end
                end
            end
      
        end

        % Button pushed function: InstalacionButton
        function InstalacionButtonPushed(app, event)
            app.InstalacionButton.ButtonPushedFcn = createCallbackFcn(app, @InstalacionButtonPushed, true);

            showInstallationView(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'logo.jpg';
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel = uilabel(app.UIFigure);
            app.CalculadoradeROIenlaimplementacindeenergasolarvselctricaLabel.Text = 'Calculadora de ROI en la implementación de energía solar vs eléctrica';
            app.Panel = uipanel(app.UIFigure);
            app.TipoDropDownLabel = uilabel(app.Panel);
            app.TipoDropDownLabel.Text = 'Tipo';
            app.TipoDropDown = uidropdown(app.Panel);
            app.TipoDropDown.Items = {'Esencial', 'No Esencial', 'Altamente Esencial'};
            app.TipoDropDown.Value = 'Esencial';
            app.NombreEditFieldLabel = uilabel(app.Panel);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.Panel, 'text');
            app.PotenciaqueconsumeenWattsEditFieldLabel = uilabel(app.Panel);
            app.PotenciaqueconsumeenWattsEditFieldLabel.Text = 'Potencia que consume (en Watts)';
            app.PotenciaEnWattsEditField = uieditfield(app.Panel, 'numeric');
            app.UsopromediodiarioenhorasEditFieldLabel = uilabel(app.Panel);
            app.UsopromediodiarioenhorasEditFieldLabel.Text = 'Uso promedio diario (en horas)';
            app.UsoEnHorasEditField = uieditfield(app.Panel, 'numeric');
            app.DiaspromediodeusoalasemanaEditFieldLabel = uilabel(app.Panel);
            app.DiaspromediodeusoalasemanaEditFieldLabel.Text = 'Dias promedio de uso a la semana';
            app.CantidadDiasEditField = uieditfield(app.Panel, 'numeric');
            app.ResultadoLabel = uilabel(app.Panel);
            app.ResultadoLabel.Visible = 'off';
            app.ResultadoLabel.Text = 'Resultado';
            app.SeleccioneDropDown = uidropdown(app.Panel);
            app.SeleccioneDropDown.Visible = 'off';
            app.SeleccioneDropDown.ClickedFcn = createCallbackFcn(app, @ModificarElectrodomesticoCallback, true);
            app.LimpiarButton = uibutton(app.Panel, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarCallback, true);
            app.LimpiarButton.Enable = 'off';
            app.LimpiarButton.Text = 'Limpiar';
            app.EliminarButton = uibutton(app.Panel, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarCallback, true);
            app.EliminarButton.Enable = 'off';
            app.EliminarButton.Text = 'Eliminar';
            app.ModificarButton = uibutton(app.Panel, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarCallback, true);
            app.ModificarButton.Enable = 'off';
            app.ModificarButton.Text = 'Modificar';
            app.CalcularPotenciaButton = uibutton(app.Panel, 'push');
            app.CalcularPotenciaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularTotalCallback, true);
            app.CalcularPotenciaButton.Enable = 'off';
            app.CalcularPotenciaButton.Text = 'Calcular Potencia';
            app.AgregarButton = uibutton(app.Panel, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarCallback, true);
            app.AgregarButton.Text = 'Agregar';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel_2);
            app.CalculadoradeConsumoButton = uibutton(app.GridLayout, 'push');
            app.CalculadoradeConsumoButton.Icon = 'calc.png';
            app.CalculadoradeConsumoButton.Text = 'Calculadora de Consumo';
            app.InstalacionButton = uibutton(app.GridLayout, 'push');
            app.InstalacionButton.ButtonPushedFcn = createCallbackFcn(app, @InstalacionButtonPushed, true);
            app.InstalacionButton.Icon = 'panel.png';
            app.InstalacionButton.Text = 'Instalación';
            app.EnergaProducidaButton = uibutton(app.GridLayout, 'push');
            app.EnergaProducidaButton.Icon = 'energia.png';
            app.EnergaProducidaButton.Text = 'Energía Producida';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.jpg';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Visible = 'off';
            app.IngresesuconsumomensualLabel = uilabel(app.Panel2);
            app.IngresesuconsumomensualLabel.Text = 'Ingrese su consumo mensual';
            app.IngreseConsumo = uieditfield(app.Panel2, 'numeric');
            app.CostoDeInstalacion = uilabel(app.Panel2);
            app.CostoDeInstalacion.Text = 'Costo de Instalacion';
            app.BateriasnecesariasEditFieldLabel = uilabel(app.Panel2);
            app.BateriasnecesariasEditFieldLabel.Text = 'Baterias necesarias';
            app.PanelesnecesariosEditFieldLabel = uilabel(app.Panel2);
            app.PanelesnecesariosEditFieldLabel.Text = 'Paneles necesarios';
            app.TerrenonecesarioEditFieldLabel = uilabel(app.Panel2);
            app.TerrenonecesarioEditFieldLabel.Text = 'Terreno necesario';
            app.BateriasnecesariasEditField = uieditfield(app.Panel2, 'text');
            app.PanelesnecesariosEditField = uieditfield(app.Panel2, 'text');
            app.TerrenonecesarioEditField = uieditfield(app.Panel2, 'text');
            app.CostototalEditFieldLabel = uilabel(app.Panel2);
            app.CostototalEditFieldLabel.Text = 'Costo total';
            app.CostototalEditField = uieditfield(app.Panel2, 'text');
            app.Image2 = uiimage(app.Panel2);
            app.Image2.ImageSource = 'bateria.png';
            app.Image2_2 = uiimage(app.Panel2);
            app.Image2_2.ImageSource = 'panelSolar.png';
            app.Image2_3 = uiimage(app.Panel2);
            app.Image2_3.ImageSource = 'terreno.png';
            app.Image2_4 = uiimage(app.Panel2);
            app.Image2_4.ImageSource = 'dineroSimbolo.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end